classdef count_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        axes1    matlab.ui.control.UIAxes
        start    matlab.ui.control.Button
        count    matlab.ui.control.Button
        stop     matlab.ui.control.Button
        text1    matlab.ui.control.Label
        text2    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function count_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to count (see VARARGIN)
            
            % Choose default command line output for count
            handles.output = hObject;
            axes(handles.axes1);
            imshow('blank.jpg');
            axis off;
            set(handles.text2,'string','0');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: count
        function count_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to count (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global vid
            
            % Set the parameters for video
            triggerconfig( vid ,'manual');                                      % the trigger occurs only after the trigger function
            set(vid, 'FramesPerTrigger',1);                                     % one frame acquired per trigger
            set(vid, 'TriggerRepeat',Inf);                                      % Keep executing the trigger every time the trigger condition is met until the stop function is called
            set(vid,'ReturnedColorSpace','rgb');                                % to get the rgb colour image
            vid.Timeout = 10;
            start(vid);
            
            while (1)
            facedetector = vision.CascadeObjectDetector;                                % Create a cascade detector object
            trigger(vid);                                                               %trigger to get the frame from the video
            image = getdata(vid);                                                       %store that frame in 'image'
            bbox = step(facedetector, image);                                           % position of face in 'bbox' (x, y, width and height)
            insert_object = insertObjectAnnotation(image,'rectangle',bbox,'Face');      % Draw the bounding box around the detected face.
            imshow(insert_object);
            axis off;                                                                   % invisible the axis from GUI
            no_rows = size(bbox,1);                                                     % get the number of rows (which will be equal to number of people)
            X = sprintf('%d', no_rows);
            set(handles.text2,'string',X);                                              %display the value of X in GUI
            end
        end

        % Button pushed function: start
        function start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global vid     % making the variable global
            vid = videoinput('winvideo' , 1, 'YUY2_640X480');
        end

        % Button pushed function: stop
        function stop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global vid
            stop(vid),clear vid %stop the running video
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'count';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_Callback, true);
            app.start.Tag = 'start';
            app.start.Text = 'START CAMERA';
            app.count = uibutton(app.figure1, 'push');
            app.count.ButtonPushedFcn = createCallbackFcn(app, @count_Callback, true);
            app.count.Tag = 'count';
            app.count.Text = ' COUNT FACE';
            app.stop = uibutton(app.figure1, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop.Tag = 'stop';
            app.stop.Text = 'STOP';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Number of People';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'text2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = count_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)count_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end